classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        ShowButton  matlab.ui.control.Button
        EnterTheAddresofUrImageEditField  matlab.ui.control.EditField
        EnterTheAddresofUrImageEditFieldLabel  matlab.ui.control.Label
        SmoothingImagesUsingGaussianFilteringLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Enter(app, event)


        end

        % Callback function
        function drawPROBGraph(app, event)
           % plot(app.Probability,[10 9 4 7],'--b');
        end

        % Callback function
        function PMF(app, event)
           
        end

        % Callback function
        function CDF(app, event)
         
        end

        % Callback function
        function histofram(app, event)
          
        end

        % Callback function
        function Xplot(app, event)
          
        end

        % Button pushed function: ShowButton
        function Show_(app, event)
           %Getting The image as an input from user.
           User_Image = app.EnterTheAddresofUrImageEditField.Value;
           %Reading the image
           Original_Image = imread (User_Image);
           %Calling the function "imgaussfilt" to filter the image using Gaussian filtering
           Filtered_Image = imgaussfilt(Original_Image, 2);
           %Displaying the original and filtered image in a montage.
           figure ('Name', 'Smoothing Images Using Gaussian Filtering');
           montage({Original_Image,Filtered_Image})
           % Giving a title to the output.
           title('The Original Image (Left) Vs. The Gaussian Filtered Image (Right)');
        end

        % Callback function
        function plotXmean(app, event)
           
        end

        % Callback function
        function plotYmean(app, event)

        end

        % Callback function
        function tMeanY(app, event)

        end

        % Callback function
        function tMeanX(app, event)
          
        end

        % Callback function
        function SACFX(app, event)
            
        end

        % Callback function
        function tACFY(app, event)
           
        end

        % Callback function
        function tacfx(app, event)
          
        end

        % Callback function
        function PSDY(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SmoothingImagesUsingGaussianFilteringLabel = uilabel(app.UIFigure);
            app.SmoothingImagesUsingGaussianFilteringLabel.Text = {'Smoothing Images Using Gaussian Filtering'; ''};
            app.EnterTheAddresofUrImageEditFieldLabel = uilabel(app.UIFigure);
            app.EnterTheAddresofUrImageEditFieldLabel.Text = 'Enter The Addres of Ur Image';
            app.EnterTheAddresofUrImageEditField = uieditfield(app.UIFigure, 'text');
            app.ShowButton = uibutton(app.UIFigure, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @Show_, true);
            app.ShowButton.Text = 'Show !';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end